classdef EBEAE_FD_FLIM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Preprocessing                  matlab.ui.container.Tab
        N2bestresultsRho001bestresultsEBEAETVbestresultsLabel_2  matlab.ui.control.Label
        UnmixingSeg                    matlab.ui.control.DropDown
        UnmixinganalysisDropDownLabel_2  matlab.ui.control.Label
        N3DefaultLabel                 matlab.ui.control.Label
        UnmixingPre                    matlab.ui.control.DropDown
        UnmixinganalysisDropDownLabel  matlab.ui.control.Label
        N2bestresultsRho001bestresultsEBEAETVbestresultsLabel  matlab.ui.control.Label
        N2DefaultLabel                 matlab.ui.control.Label
        rho01DefaultLabel_2            matlab.ui.control.Label
        RhoSegEditField                matlab.ui.control.NumericEditField
        RhoEditFieldLabel_2            matlab.ui.control.Label
        SetupsegmentationLabel         matlab.ui.control.Label
        rho01DefaultLabel              matlab.ui.control.Label
        RhoPreEditField                matlab.ui.control.NumericEditField
        RhoEditFieldLabel              matlab.ui.control.Label
        FinalsampleEditField           matlab.ui.control.NumericEditField
        FinalsampleEditFieldLabel      matlab.ui.control.Label
        InitialsampleEditField         matlab.ui.control.NumericEditField
        InitialsampleEditFieldLabel    matlab.ui.control.Label
        Progress1                      matlab.ui.control.TextArea
        N_EM                           matlab.ui.control.DropDown
        NoEndMembersDropDownLabel      matlab.ui.control.Label
        SetupbackgroundremoveLabel     matlab.ui.control.Label
        Lamp_pre                       matlab.ui.control.Lamp
        BackgroundremoveSegmentationLabel  matlab.ui.control.Label
        Pre                            matlab.ui.control.StateButton
        LabelsTab                      matlab.ui.container.Tab
        NoTumor2Tumor3NALabel          matlab.ui.control.Label
        FiniL                          matlab.ui.control.NumericEditField
        FinalsampleEditField_2Label    matlab.ui.control.Label
        IniL                           matlab.ui.control.NumericEditField
        InitialsampleEditField_2Label  matlab.ui.control.Label
        NextsampleButton               matlab.ui.control.StateButton
        EndMember3Spinner              matlab.ui.control.Spinner
        EndMember3SpinnerLabel         matlab.ui.control.Label
        EndMember2Spinner              matlab.ui.control.Spinner
        EndMember2SpinnerLabel         matlab.ui.control.Label
        EndMember1Spinner              matlab.ui.control.Spinner
        EndMember1SpinnerLabel         matlab.ui.control.Label
        DatabasesupervisedlabelingLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        FlimImage                      matlab.ui.control.UIAxes
        EMmean                         matlab.ui.control.UIAxes
        CreateDatabaseTab              matlab.ui.container.Tab
        ProgressData                   matlab.ui.control.TextArea
        LampData                       matlab.ui.control.Lamp
        TableData                      matlab.ui.control.Table
        Database                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        E1 = 1;
        E2 = 1;
        E3 = 1;
        WaitB = true;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Pre
        function PreValueChanged(app, event)
            addpath(fileparts(mfilename('fullpath')));   % carpeta del .mlapp en el path
            addpath(fullfile(appRoot, 'Database'));      % archivos HDF5
            % Turn on indicators
            clc
            app.Lamp_pre.Color = 'yellow';
            progress = sprintf('Progress: %.2f', 0.00);
            app.Progress1.Value = progress;
            app.Pre.Value = false;
            
            % N end-members
            N = str2double(app.N_EM.Value);
            Sini = app.InitialsampleEditField.Value;
            Sfini = app.FinalsampleEditField.Value;
            rho_pre = app.RhoPreEditField.Value;
            rho_seg = app.RhoSegEditField.Value;
            UnmixingSelectPre = app.UnmixingPre.Value;
            UnmixingSelectSeg = app.UnmixingSeg.Value;
            
            % Asignar un valor numérico a cada opción
            switch UnmixingSelectPre
                case 'EBEAE'
                    analysisType1 = 0;
                case 'EBEAE-SN'
                    analysisType1 = 1;
                case 'EBEAE-TV'
                    analysisType1 = 2;
            end
            % Asignar un valor numérico a cada opción
            switch UnmixingSelectSeg
                case 'EBEAE'
                    analysisType2 = 0;
                case 'EBEAE-SN'
                    analysisType2 = 1;
                case 'EBEAE-TV'
                    analysisType2 = 2;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Load HDF5 Datasets of Frequency Domain FLIM Features
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Define the wavelengths and channels
            wavelengths = [375, 445]; % 374nm & 445nm wavelength
            channels_375 = 1:4; % 4 channels of data
            channels_445 = 2:4; % 3 channels of data
            
            % Each channel contains 9 distinct features defined as...
            features = {'Calibrated Absolute Int', 'Calibrated Inter Normalized Int', ...
                'Calibrated Intra Normalized Int','Freq 2/cmod', 'Freq 2/cphase', ...
                'Freq 3/cmod', 'Freq 3/cphase', 'Freq 4/cmod', 'Freq 4/cphase'};
            
            % Read one piece of feature data to get the size
            path = sprintf('/Calibrated Data/Channel %d/%d/%s',...
            channels_375(1), wavelengths(1), features{1});
            feature_data = h5read(char('13_03_08-763_GU-19-105-Tumor_Frozen_Proc.hdf5'), path);
                           
            % Get the size of the feature data
            x_pixels = size(feature_data, 1);
            y_pixels = size(feature_data, 2);


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Analysis of FLIM images in the database
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            for i=Sini:Sfini % The database contains 51 samples
                Abundance = []; % Variable where the results of classification by maximum abundance will be stored
                EndMembers = []; % Variable where the end-members calculated by the EBEAE algorithm will be stored
                FLIMfeatures = []; % Features Original  
                ResultsGlobal = []; % Features Original  
                app.Progress1.Value = '';
                progress = sprintf('Progress: %.2f', (i*100)/51);
                app.Progress1.Value = progress; drawnow;

                % Function that will perform the EBEAE algorithm
                [ResultBlind,P1ResBlind,Zh1ResBlind,A1ResBlind]  = BackRem(N,rho_pre,rho_seg,i,features,x_pixels,y_pixels,wavelengths,channels_445,channels_375,feature_data,analysisType1,analysisType2);          
               
                % Store the results of the sample i
                ResultsGlobal = [ResultsGlobal; ResultBlind];
                EndMembers = [EndMembers; P1ResBlind];
                FLIMfeatures = [FLIMfeatures; Zh1ResBlind];
                Abundance = [Abundance; A1ResBlind];

                % Save the results of the sample i
                Route1= fullfile(appRoot, 'GlobalResults'); 
                Rfile = ['GlobalResult', num2str(i), '.mat'];
                file1 = fullfile(Route1, Rfile);
                if ~exist(Route1, 'dir') mkdir(Route1); end
                save(file1,"ResultsGlobal")

                Route2= fullfile(appRoot, 'EndMembersFeatures'); 
                Rfile2 = ['FeaturesEndMembers', num2str(i), '.mat'];
                file2 = fullfile(Route2, Rfile2);
                if ~exist(Route2, 'dir') mkdir(Route2); end
                save(file2,"EndMembers")

                Route3= fullfile(appRoot, 'PixelFeatures');     
                Rfile3 = ['PixelFeatures', num2str(i), '.mat'];
                file3 = fullfile(Route3, Rfile3);
                if ~exist(Route3, 'dir') mkdir(Route3); end
                save(file3,"FLIMfeatures")

                Route4= fullfile(appRoot, 'MaxAbundance');
                Rfile4 = ['SegResMaxAbundance', num2str(i), '.mat'];
                file4 = fullfile(Route4, Rfile4);
                if ~exist(Route4, 'dir') mkdir(Route4); end
                save(file4,"Abundance")

                app.Progress1.Value = '';

            end

            % Turn off progress
            app.Progress1.Value = '';
            progress = sprintf('Progress: %.2f', 100.00);
            app.Progress1.Value = progress;  
            app.Lamp_pre.Color = 'green';
            pause(2)
            app.Lamp_pre.Color = 'red';
            app.Pre.BackgroundColor = 'white'; 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             addpath(fileparts(mfilename('fullpath')));
            Label = [];
            Lini = app.IniL.Value;
            Lfini = app.FiniL.Value;

            for sample=Lini:Lfini

                [EM1,EM2,EM3] = LabelPixelLevel(sample);
                
                % Show mean pixel features by each segmented area
                semilogy(app.EMmean, mean(EM1,2), 'b', 'LineWidth', 1); 
                hold(app.EMmean,"on")
                semilogy(app.EMmean, mean(EM2,2), 'r', 'LineWidth', 1); 
                semilogy(app.EMmean, mean(EM3,2), 'k', 'LineWidth', 1); 
                grid(app.EMmean, 'on');
                axis(app.EMmean, 'tight')
                hold(app.EMmean,"off")
                legend(app.EMmean,'EM1','EM2','EM3')
                
                % Show image segmentation results
                Route5= fullfile(appRoot, 'ImagesSegmentation'); 
                Rfile5 = ['Sample', num2str(sample), 'EBEAE_LastIter_Ft1.png'];
                file5 = fullfile(Route5, Rfile5);
                FlimIm = imread(file5);
                imshow(FlimIm, 'Parent', app.FlimImage);
                axis(app.FlimImage, 'off');
                
%                 % Capture user data

                
                while app.WaitB
                    pause(0.1);
                    drawnow;
                end

                app.WaitB = true;
                app.NextsampleButton.Value = false;

                Label = [Label; app.E1 app.E2 app.E3];

            end

           
            Route6= fullfile(appRoot, 'Labels'); 
            Rfile6 = 'Labels';
            file6 = fullfile(Route6, Rfile6);
            if ~exist(Route6, 'dir') mkdir(Route6); end
            save(file6,"Label")

        end

        % Value changed function: NextsampleButton
        function NextsampleButtonValueChanged(app, event)
             app.E1 = app.EndMember1Spinner.Value;
             app.E2 = app.EndMember2Spinner.Value;
             app.E3 = app.EndMember3Spinner.Value;
             app.WaitB = false;
        end

        % Button pushed function: Database
        function DatabasePushed(app, event)
%             addpath('MaxAbundance')
%             addpath('PixelFeatures')
%             addpath('BackgroundData')
%             addpath('Labels')
            clc
            ruta_app = appRoot;
            ruta_archivo = fullfile(ruta_app, 'Labels', 'Labels.mat');
            Labels1 = load(ruta_archivo);
            Labels = Labels1.Label;

            app.LampData.Color = 'yellow';
            progress = sprintf('Progress: %.2f', 0.00);
            app.ProgressData.Value = progress; drawnow;
            app.Pre.Value = false;

            Database1 = [];

            for sample=1:51%51
                ruta_archivo = fullfile(ruta_app, 'PixelFeatures', ['PixelFeatures',num2str(sample),'.mat']);
                FLIMfeatures1 = load(ruta_archivo);
                FLIMfeatures = FLIMfeatures1.FLIMfeatures;

                ruta_archivo = fullfile(ruta_app, 'MaxAbundance', ['SegResMaxAbundance',num2str(sample),'.mat']);
                Abundance1 = load(ruta_archivo);
                Abundance = Abundance1.Abundance;

                ruta_archivo = fullfile(ruta_app, 'BackgroundData', ['BackgroundData_',num2str(sample),'.mat']);
                ZB1 = load(ruta_archivo);
                ZB = ZB1.ZB;

                app.ProgressData.Value = '';
                progress = sprintf('Progress: %.2f', (sample*100)/51);
                app.ProgressData.Value = progress; drawnow;

                [~, i_max] = max(Abundance, [], 1);
            
                Features = [];
                
                % Supervised Label
                for i=1:size(FLIMfeatures,2)
                    if Labels(sample,i_max(1,i))==1
                        Features = [Features; FLIMfeatures(:,i)' Labels(sample,i_max(1,i))];
                    elseif Labels(sample,i_max(1,i))==2
                        Features = [Features; FLIMfeatures(:,i)' Labels(sample,i_max(1,i))];
                    else
                        Features = [Features; FLIMfeatures(:,i)' Labels(sample,i_max(1,i))];
                    end
                end
                
                % Sample split
                
                NoTumor = [];
                Tumor = [];
                Unknown = [];
                for i=1:size(Features,1)
                    if Features(i,end)==1
                        NoTumor = [NoTumor;Features(i,1:63)];
                    elseif Features(i,end)==2
                        Tumor = [Tumor;Features(i,1:63)];
                    else
                        Unknown = [Unknown;Features(i,1:63)];
                    end
                end
                  
                % Mean values
                ZB = ZB';
                AvgNoise = mean(ZB,1);
            
                if ~isempty(NoTumor)
                    AvgNoTumor = mean(NoTumor,1);
                    PreData = [AvgNoTumor 1 sample];
                    Database1 = [Database1; PreData];
                end
            
                if ~isempty(Tumor)
                    AvgTumor = mean(Tumor,1);
                    PreData = [AvgTumor 2 sample];
                    Database1 = [Database1; PreData];
                end
            
                PreData = [AvgNoise 8 sample];
                Database1 = [Database1; PreData];
            
                if ~isempty(Unknown)
                    AvgUnknown = mean(Unknown,1);
                    PreData = [AvgUnknown 0 sample];
                    Database1 = [Database1; PreData];
                end

            end
            
            Route7= fullfile(appRoot, 'DatasetFinal'); 
            Rfile7 = 'DatabaseNum';
            file7 = fullfile(Route7, 'MachineLearning', Rfile7);
            if ~exist(Route7, 'dir') mkdir(Route7); end
            save(file7,"Database1")


            [DatabaseML] = TableDatabase(Database1);
            app.TableData.Data = DatabaseML;
            ColName = arrayfun(@(x) sprintf('Feature %d', x), 1:62, 'UniformOutput', false);
            ColName{63} = 'Label';
            app.TableData.ColumnName = ColName;
            
            % Rfile8 = 'TableData';
            % file8 = fullfile(Route7, Rfile8);
            % save(file8,"DatabaseML")

            app.ProgressData.Value = '';
            progress = sprintf('Progress: %.2f', 100.00);
            app.ProgressData.Value = progress;  drawnow;
            app.LampData.Color = 'green';
            pause(2)
            app.LampData.Color = 'red';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Preprocessing = uitab(app.TabGroup);
            app.Preprocessing.Title = 'Preprocessing';
            app.Pre = uibutton(app.Preprocessing, 'state');
            app.Pre.ValueChangedFcn = createCallbackFcn(app, @PreValueChanged, true);
            app.Pre.Text = 'Start process';
            app.BackgroundremoveSegmentationLabel = uilabel(app.Preprocessing);
            app.BackgroundremoveSegmentationLabel.Text = 'Background remove & Segmentation';
            app.Lamp_pre = uilamp(app.Preprocessing);
            app.SetupbackgroundremoveLabel = uilabel(app.Preprocessing);
            app.SetupbackgroundremoveLabel.Text = 'Setup background remove';
            app.NoEndMembersDropDownLabel = uilabel(app.Preprocessing);
            app.NoEndMembersDropDownLabel.Text = 'No. End-Members';
            app.N_EM = uidropdown(app.Preprocessing);
            app.N_EM.Items = {'2', '3'};
            app.N_EM.Value = '2';
            app.Progress1 = uitextarea(app.Preprocessing);
            app.InitialsampleEditFieldLabel = uilabel(app.Preprocessing);
            app.InitialsampleEditFieldLabel.Text = 'Initial sample';
            app.InitialsampleEditField = uieditfield(app.Preprocessing, 'numeric');
            app.InitialsampleEditField.Limits = [1 51];
            app.InitialsampleEditField.Value = 1;
            app.FinalsampleEditFieldLabel = uilabel(app.Preprocessing);
            app.FinalsampleEditFieldLabel.Text = 'Final sample';
            app.FinalsampleEditField = uieditfield(app.Preprocessing, 'numeric');
            app.FinalsampleEditField.Limits = [1 51];
            app.FinalsampleEditField.Value = 51;
            app.RhoEditFieldLabel = uilabel(app.Preprocessing);
            app.RhoEditFieldLabel.Text = 'Rho';
            app.RhoPreEditField = uieditfield(app.Preprocessing, 'numeric');
            app.RhoPreEditField.Limits = [0 1];
            app.RhoPreEditField.Value = 0.01;
            app.rho01DefaultLabel = uilabel(app.Preprocessing);
            app.rho01DefaultLabel.Interpreter = 'latex';
            app.rho01DefaultLabel.Text = '$\rho = 0.1$ (Default)';
            app.SetupsegmentationLabel = uilabel(app.Preprocessing);
            app.SetupsegmentationLabel.Text = 'Setup segmentation';
            app.RhoEditFieldLabel_2 = uilabel(app.Preprocessing);
            app.RhoEditFieldLabel_2.Text = 'Rho';
            app.RhoSegEditField = uieditfield(app.Preprocessing, 'numeric');
            app.RhoSegEditField.Limits = [0 1];
            app.RhoSegEditField.Value = 0.1;
            app.rho01DefaultLabel_2 = uilabel(app.Preprocessing);
            app.rho01DefaultLabel_2.Interpreter = 'latex';
            app.rho01DefaultLabel_2.Text = '$\rho = 0.1$ (Default)';
            app.N2DefaultLabel = uilabel(app.Preprocessing);
            app.N2DefaultLabel.Text = 'N = 2 (Default)';
            app.N2bestresultsRho001bestresultsEBEAETVbestresultsLabel = uilabel(app.Preprocessing);
            app.N2bestresultsRho001bestresultsEBEAETVbestresultsLabel.Text = {'N = 2 (remmendation for best results)'; 'Rho = 0.01 (recommendation for best results)'; 'Unmixing algorithm = EBEAE TV '; '(recommendation for best results)'};
            app.UnmixinganalysisDropDownLabel = uilabel(app.Preprocessing);
            app.UnmixinganalysisDropDownLabel.Text = 'Unmixing analysis';
            app.UnmixingPre = uidropdown(app.Preprocessing);
            app.UnmixingPre.Items = {'EBEAE', 'EBEAE-SN', 'EBEAE-TV'};
            app.UnmixingPre.Value = 'EBEAE-TV';
            app.N3DefaultLabel = uilabel(app.Preprocessing);
            app.N3DefaultLabel.Text = 'N = 3 (Default)';
            app.UnmixinganalysisDropDownLabel_2 = uilabel(app.Preprocessing);
            app.UnmixinganalysisDropDownLabel_2.Text = 'Unmixing analysis';
            app.UnmixingSeg = uidropdown(app.Preprocessing);
            app.UnmixingSeg.Items = {'EBEAE', 'EBEAE-SN', 'EBEAE-TV'};
            app.UnmixingSeg.Value = 'EBEAE';
            app.N2bestresultsRho001bestresultsEBEAETVbestresultsLabel_2 = uilabel(app.Preprocessing);
            app.N2bestresultsRho001bestresultsEBEAETVbestresultsLabel_2.Text = {'N = 3 (remmendation for best results)'; 'Rho = 0.1 (recommendation for best results)'; 'Unmixing algorithm = EBEAE '; '(recommendation for best results)'};
            app.LabelsTab = uitab(app.TabGroup);
            app.LabelsTab.Title = 'Labels';
            app.EMmean = uiaxes(app.LabelsTab);
            zlabel(app.EMmean, 'Z')
            app.FlimImage = uiaxes(app.LabelsTab);
            zlabel(app.FlimImage, 'Z')
            app.StartButton = uibutton(app.LabelsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DatabasesupervisedlabelingLabel = uilabel(app.LabelsTab);
            app.DatabasesupervisedlabelingLabel.Text = 'Database supervised labeling';
            app.EndMember1SpinnerLabel = uilabel(app.LabelsTab);
            app.EndMember1SpinnerLabel.Text = 'End-Member 1';
            app.EndMember1Spinner = uispinner(app.LabelsTab);
            app.EndMember1Spinner.Limits = [0 2];
            app.EndMember1Spinner.Value = 1;
            app.EndMember2SpinnerLabel = uilabel(app.LabelsTab);
            app.EndMember2SpinnerLabel.Text = 'End-Member 2';
            app.EndMember2Spinner = uispinner(app.LabelsTab);
            app.EndMember2Spinner.Limits = [0 2];
            app.EndMember2Spinner.Value = 2;
            app.EndMember3SpinnerLabel = uilabel(app.LabelsTab);
            app.EndMember3SpinnerLabel.Text = 'End-Member 3';
            app.EndMember3Spinner = uispinner(app.LabelsTab);
            app.EndMember3Spinner.Limits = [0 2];
            app.NextsampleButton = uibutton(app.LabelsTab, 'state');
            app.NextsampleButton.ValueChangedFcn = createCallbackFcn(app, @NextsampleButtonValueChanged, true);
            app.NextsampleButton.Text = 'Next sample';
            app.InitialsampleEditField_2Label = uilabel(app.LabelsTab);
            app.InitialsampleEditField_2Label.Text = 'Initial sample';
            app.IniL = uieditfield(app.LabelsTab, 'numeric');
            app.IniL.Limits = [1 51];
            app.IniL.Value = 1;
            app.FinalsampleEditField_2Label = uilabel(app.LabelsTab);
            app.FinalsampleEditField_2Label.Text = 'Final sample';
            app.FiniL = uieditfield(app.LabelsTab, 'numeric');
            app.FiniL.Limits = [1 51];
            app.FiniL.Value = 1;
            app.NoTumor2Tumor3NALabel = uilabel(app.LabelsTab);
            app.NoTumor2Tumor3NALabel.Text = {'1 = ''No Tumor'''; '2 = ''Tumor'''; '3 = ''NA'''};
            app.CreateDatabaseTab = uitab(app.TabGroup);
            app.CreateDatabaseTab.Title = 'Create Database';
            app.Database = uibutton(app.CreateDatabaseTab, 'push');
            app.Database.ButtonPushedFcn = createCallbackFcn(app, @DatabasePushed, true);
            app.Database.Text = 'Generate';
            app.TableData = uitable(app.CreateDatabaseTab);
            app.TableData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableData.RowName = {};
            app.LampData = uilamp(app.CreateDatabaseTab);
            app.ProgressData = uitextarea(app.CreateDatabaseTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBEAE_FD_FLIM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end